classdef reconnaissance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChargerunfichierButton          matlab.ui.control.Button
        EnvoyercommandeauvhiculeButton  matlab.ui.control.Button
        JouerEnregistrementButton       matlab.ui.control.Button
        VocabulaireListBox              matlab.ui.control.ListBox
        VocabulaireListBoxLabel         matlab.ui.control.Label
        PassifLamp                      matlab.ui.control.Lamp
        PassifLampLabel                 matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        EditField                       matlab.ui.control.EditField
        ReconnaitreButton               matlab.ui.control.Button
        EnregistrerButton               matlab.ui.control.Button
        ReprsentationDesGraphesPanel    matlab.ui.container.Panel
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd 'C:\Users\Massamba Sene\Documents\GIT\DIC1\Projets\speech_recognition\Reconnaissance\hmm';
            load('matlab2.mat')
            c = struct2cell(vocabulary.words);
            for i=1:10
                app.VocabulaireListBox.Items(end+1) = cellstr(c{i}.name);
            end
            %b = Bluetooth("HC-06",1);
            %export2wsdlg("bluetooth","b",{b});
            %fopen(b);
        end

        % Button pushed function: EnregistrerButton
        function EnregistrerButtonPushed(app, event)
            value = app.Switch.Value;
            app.ReprsentationDesGraphesPanel.AutoResizeChildren="off";
            ax1 = subplot(1,1,1,'Parent',app.ReprsentationDesGraphesPanel);
            if value == "MFCC"
               cd 'C:\Users\Massamba Sene\Documents\GIT\DIC1\Projets\speech_recognition\Reconnaissance\classique';
               recObj = audiorecorder(44100,24,1);
               app.PassifLamp.Color = [0.00,1.00,0.00];
               app.PassifLampLabel.Text = "Actif";
               recObj.record(4);
               while recObj.isrecording()
                   pause(0.1);
                   plot(ax1,recObj.getaudiodata());
                   drawnow();
               end
               app.PassifLamp.Color = [1.00,0.00,0.00];
               app.PassifLampLabel.Text = "Passif";
               play(recObj);
               voice_1=getaudiodata(recObj);
               [b,a] = butter(4,4000/44100); %to filter high frequency noise.
               x=filter(b,a,voice_1);
               x=x/abs(max(x));
               fs=44100;
               frame_duration=0.02;
               frame_length=floor(frame_duration*fs);
               y=length(x);
               net_frame=floor(y/frame_length);
               new_sig=zeros(y,1);
               count=0;
               for k=1:net_frame
                   frame=x((k-1)*frame_length+1:frame_length*k);
                   amplitude=max(frame);
                   if (amplitude > 0.2)
                       count=count+1;
                       new_sig((count-1)*frame_length+1:frame_length*count)=frame;
                   end
               end
               j = length(new_sig);
               while (new_sig(j)<0.2)
                   j=j-1;
               end
               j = j+2000;
               x=new_sig(1:j);
               audiowrite("test.m4a",x,44100);
               reset(ax1);
               plot(ax1,x);
               app.EditField.Value ="";
            elseif value == "HMM"  
               cd 'C:\Users\Massamba Sene\Documents\GIT\DIC1\Projets\speech_recognition\Reconnaissance\hmm';
               recObj = audiorecorder(8000,24,1);
               pause(1);
               app.PassifLamp.Color = [0.00,1.00,0.00];
               app.PassifLampLabel.Text = "Actif";
               recObj.record(4);
               while recObj.isrecording()
                   pause(0.1);
                   plot(ax1,recObj.getaudiodata());
                   drawnow();
               end
                app.PassifLamp.Color = [1.00,0.00,0.00];
                app.PassifLampLabel.Text = "Passif";
                play(recObj);
                voice_1=getaudiodata(recObj);
                [b,a] = butter(4,800/8000); %to filter high frequency noise.
                x=filter(b,a,voice_1);
                x=x/abs(max(x));
                fs=44100;
                frame_duration=0.02;
                frame_length=floor(frame_duration*fs);
                y=length(x);
                net_frame=floor(y/frame_length);
                new_sig=zeros(y,1);
                count=0;
                for k=1:net_frame
                    frame=x((k-1)*frame_length+1:frame_length*k);
                    amplitude=max(frame);
                    if (amplitude > 0.2)
                        count=count+1;
                        new_sig((count-1)*frame_length+1:frame_length*count)=frame;
                    end
                end
                j = length(new_sig);
                while (new_sig(j)<0.2)
                    j=j-1;
                end
                x=new_sig(1:j);
                audiowrite("test.wav",x,8000);
                reset(ax1);
                plot(ax1,x);
                app.EditField.Value ="";
            end
        end

        % Button pushed function: ReconnaitreButton
        function ReconnaitreButtonPushed(app, event)
            app.ReprsentationDesGraphesPanel.AutoResizeChildren = "off";
            value = app.Switch.Value;
            if value == "MFCC"
               cd 'C:\Users\Massamba Sene\Documents\GIT\DIC1\Projets\speech_recognition\Reconnaissance\classique';
               x = mfcc_calc("test.m4a");
               paths = ["avancer.m4a" "reculer.m4a" "gauche.m4a" "droite.m4a" "stop.m4a" "allumer.m4a" "eteindre.m4a" "bas.m4a" "haut.m4a" "demo.m4a"];
               d = [zeros(1,10)];
               for k=1:10
                   variable = mfcc_calc(paths(k));
                   d(k) = norm(x-variable);
               end
               [~,index]=min(d);
               for k = paths
                   if index == find(paths==k)
                       clc;
                       [data1,fs1] = audioread("test.m4a");
                       [data2,fs2] = audioread(k);
                       sound(data2,50000);
                       win = hann(1024,"periodic")
                       coeff1 = mfcc(data1,fs1);
                       coeff2 = mfcc(data2,fs2);
                       ax1 = subplot(2,2,1,'Parent',app.ReprsentationDesGraphesPanel);
                       ax2 = subplot(2,2,2,'Parent',app.ReprsentationDesGraphesPanel);
                       ax3 = subplot(2,2,3,'Parent',app.ReprsentationDesGraphesPanel);
                       ax4 = subplot(2,2,4,'Parent',app.ReprsentationDesGraphesPanel);
                       imagesc(ax1,coeff1);
                       axis auto
                       colormap("jet");
                       title(ax1,"MFCC de l'enregistrement");
                       imagesc(ax2,coeff2);
                       axis auto
                       colormap("jet");
                       title(ax2,"MFCC du fichier correspondant");
                       plot(ax3,data1);
                       title(ax1,"Signal enregistré");
                       plot(ax4,data2);
                       title(ax2,"Signal reconnu");
                       [~,name,~] = fileparts(k);
                       app.EditField.Value = "Resultat de la prediction est: " + name;
                   end
               end
            elseif value == "HMM"
                cd 'C:\Users\Massamba Sene\Documents\GIT\DIC1\Projets\speech_recognition\Reconnaissance\hmm';
                load('matlab2.mat');
                prediction = vocabulary.recognize({audioread("test.wav")});
                ax1 = subplot(2,1,1,'Parent',app.ReprsentationDesGraphesPanel);
                ax2 = subplot(2,1,2,'Parent',app.ReprsentationDesGraphesPanel);
                plot(ax1,audioread("test.wav"));
                title(ax1,"Signal enregistré");
                txt = sprintf("audio/%s/%s01.wav",cell2mat(prediction),cell2mat(prediction));
                plot(ax2,audioread(txt));
                title(ax2,"Signal reconnu");
                app.EditField.Value = "Resultat de la prediction est " + prediction;
            end
        end

        % Callback function
        function RinitialiserButtonPushed(app, event)

        end

        % Button pushed function: JouerEnregistrementButton
        function JouerEnregistrementButtonPushed(app, event)
           val = app.Switch.Value;
           if val == "MFCC"
               cd 'C:\Users\Massamba Sene\Documents\GIT\DIC1\Projets\speech_recognition\Reconnaissance\classique';
               sound(audioread("test.m4a"),44100)
           elseif val == "HMM"
               cd 'C:\Users\Massamba Sene\Documents\GIT\DIC1\Projets\speech_recognition\Reconnaissance\hmm';
               sound(audioread("test.wav"),8000)
           end
        end

        % Button pushed function: EnvoyercommandeauvhiculeButton
        function EnvoyercommandeauvhiculeButtonPushed(app, event)
            val = app.Switch.Value;
            if val == "MFCC"
                cd 'C:\Users\Massamba Sene\Documents\GIT\DIC1\Projets\speech_recognition\Reconnaissance\classique';
                x = mfcc_calc("test.m4a");
                paths = ["avancer.m4a" "reculer.m4a" "gauche.m4a" "droite.m4a" "stop.m4a" "allumer.m4a" "eteindre.m4a" "bas.m4a" "haut.m4a" "demo.m4a"];
                d = [zeros(1,10)];
                for k=1:10
                    variable = mfcc_calc(paths(k));
                    d(k) = norm(x-variable);
                end
                [~,index]=min(d);
                for k = paths
                    if index == find(paths==k)
                        [~,name,~] = fileparts(k);
                        app.EditField.Value = "Resultat de la prediction est: " + name;
                    end
                end
                if name == "avancer"
                    state = 1;
                elseif name == "reculer"
                    state = 2;
                elseif name == "gauche"
                    state = 3;
                elseif name == "droite"
                    state = 4;
                elseif name == "stop"
                    state = 5;
                elseif name == "demo"
                    state = 6;
                end
                b = evalin('base','b');
                fwrite(b,state);
            elseif val == "HMM"
                cd 'C:\Users\Massamba Sene\Documents\GIT\DIC1\Projets\speech_recognition\Reconnaissance\hmm';
                load('matlab2.mat');
                prediction = vocabulary.recognize({audioread("test.wav")});
                if prediction == "avancer"
                    state = 1;
                elseif prediction == "reculer"
                    state = 2;
                elseif prediction == "gauche"
                    state = 3;
                elseif prediction == "droite"
                    state = 4;
                elseif prediction == "stop"
                    state = 5;
                end
                b = evalin('base','b');
                fwrite(b,state);
            end
        end

        % Button pushed function: ChargerunfichierButton
        function ChargerunfichierButtonPushed(app, event)
            value = app.Switch.Value;
            if value == "MFCC"
                [file,path] = uigetfile('*.m4a')
                copyfile(strcat(path,file),strcat(path,"test.m4a"))
            elseif value == "HMM"
                [file,path] = uigetfile('*.wav')
                copyfile(strcat(path,file),strcat(path,"test.wav"))
                movefile(strcat(path,"test.wav"),strcat("C:\Users\Massamba Sene\Documents\GIT\DIC1\Projets\speech_recognition\Reconnaissance\hmm\","test.wav"))
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ReprsentationDesGraphesPanel = uipanel(app.UIFigure);
            app.ReprsentationDesGraphesPanel.Title = 'Représentation Des Graphes';
            app.EnregistrerButton = uibutton(app.UIFigure, 'push');
            app.EnregistrerButton.ButtonPushedFcn = createCallbackFcn(app, @EnregistrerButtonPushed, true);
            app.EnregistrerButton.Text = 'Enregistrer';
            app.ReconnaitreButton = uibutton(app.UIFigure, 'push');
            app.ReconnaitreButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnaitreButtonPushed, true);
            app.ReconnaitreButton.Text = 'Reconnaitre';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'HMM', 'MFCC'};
            app.Switch.Value = 'MFCC';
            app.PassifLampLabel = uilabel(app.UIFigure);
            app.PassifLampLabel.Text = 'Passif';
            app.PassifLamp = uilamp(app.UIFigure);
            app.VocabulaireListBoxLabel = uilabel(app.UIFigure);
            app.VocabulaireListBoxLabel.Text = 'Vocabulaire';
            app.VocabulaireListBox = uilistbox(app.UIFigure);
            app.VocabulaireListBox.Items = {};
            app.VocabulaireListBox.Value = {};
            app.JouerEnregistrementButton = uibutton(app.UIFigure, 'push');
            app.JouerEnregistrementButton.ButtonPushedFcn = createCallbackFcn(app, @JouerEnregistrementButtonPushed, true);
            app.JouerEnregistrementButton.Text = {'Jouer Enregistrement'; ''};
            app.EnvoyercommandeauvhiculeButton = uibutton(app.UIFigure, 'push');
            app.EnvoyercommandeauvhiculeButton.ButtonPushedFcn = createCallbackFcn(app, @EnvoyercommandeauvhiculeButtonPushed, true);
            app.EnvoyercommandeauvhiculeButton.Text = 'Envoyer commande au véhicule';
            app.ChargerunfichierButton = uibutton(app.UIFigure, 'push');
            app.ChargerunfichierButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerunfichierButtonPushed, true);
            app.ChargerunfichierButton.Text = 'Charger un fichier';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reconnaissance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end